classdef virusSpread < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulatinganExponentialSpreadUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        SimulateButton          matlab.ui.control.Button
        StatusPanel             matlab.ui.container.Panel
        GridLayout7             matlab.ui.container.GridLayout
        Label                   matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        InputParametersPanel    matlab.ui.container.Panel
        GridLayout8             matlab.ui.container.GridLayout
        RateofInfectionPanel    matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        AgentSizeSlider         matlab.ui.control.Slider
        SocialInteractionPanel  matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        InitialVelocitySlider   matlab.ui.control.Slider
        RecoveryPeriodPanel     matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        ServiceTimeSlider       matlab.ui.control.Slider
        Button                  matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        GridLayout9             matlab.ui.container.GridLayout
        HealthyLabel            matlab.ui.control.Label
        InfectedLabel           matlab.ui.control.Label
        RecoveredLabel          matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Panel_2                 matlab.ui.container.Panel
        Panel_3                 matlab.ui.container.Panel
        initializingLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        sliderNames
    end
    
    methods (Access = private)
        
        function animateSim(app,R,out)
            
            allPos = out.yout{1}.Values.Data;
            xData = squeeze(allPos(:,1,:));
            yData = squeeze(allPos(:,2,:));
            allStatuses = out.yout{2}.Values.Data;

            %% Upper subplot
            ax = app.UIAxes2;
            cla(ax)
            ax.Interactions = [];
            ax.XLim = [-1 1]*1.1;
            ax.YLim = [-1 1]*0.55;
            rectangle(ax,'Position',[-1 -0.5 2 1])  % The boundary rectangle
            
            % Create the 50 dots using rectangle
            [N,nPoints] = size(xData);
            for i = 1:N
                hr(i) = rectangle(ax,'Position',[0 0 R R],'Curvature',[1 1],'FaceColor',[0 1 0],'EdgeColor',[0 1 0]);  %#ok<AGROW>
            end
            
            %% Bottom subplot
            mp = app.UIAxes;
            cla(mp)
            mp.Interactions = [];
            
            mp.XAxis.Limits = [0,nPoints];
            mp.YAxis.Limits = [0,50];
            hold(mp,'on')
            s1 = stem(mp,0,0, 'LineWidth', 2, 'color', [0.00,0.45,0.74] , 'Marker', 'none');
            s2 = stem(mp,0,0, 'LineWidth', 2, 'color', [0.39,0.83,0.07], 'Marker', 'none');
            s3 = stem(mp,0,0, 'LineWidth', 2, 'color',[0.85,0.33,0.10], 'Marker','none');
            hold(mp,'off')
            
            for kk = 1:nPoints
                    % Update agent positions in upper subplot
                for i = 1:length(hr)
                    hr(i).Position = [xData(i,kk) yData(i,kk) R R];
                end

                currentStatusLevel2 = allStatuses(kk,:)';
                [row , ~] = find(currentStatusLevel2);
                [StatusLevel1, ~] = find(currentStatusLevel2==0);
                [StatusLevel2 , ~] = find(currentStatusLevel2==1);
                [StatusLevel3 , ~] = find(currentStatusLevel2==2);
                
                for i = 1:length(row)
                    if currentStatusLevel2(row(i)) == 1
                        hr(row(i)).FaceColor = [1 0 0];
                        hr(row(i)).EdgeColor = [1 0 0];
                    end
                    if currentStatusLevel2(row(i)) == 2
                        hr(row(i)).FaceColor = [0 0 1];
                        hr(row(i)).EdgeColor = [0 0 1];
                    end
                end
                
                StatusLevel1Count = length(StatusLevel1);
                StatusLevel2Count = length(StatusLevel2);
                StatusLevel3Count = length(StatusLevel3);

                s1.XData(kk) = kk;
                s2.XData(kk) = kk;
                s3.XData(kk) = kk;
                
                s1.YData(kk) = StatusLevel2Count+StatusLevel1Count+StatusLevel3Count;
                s2.YData(kk) = StatusLevel2Count+StatusLevel1Count;
                s3.YData(kk) = StatusLevel2Count;
                
                % Tip: When running not deployed, try commenting out
                % "limitrate" to slowdown the animation
                drawnow limitrate
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Copyright 2020 The MathWorks, Inc.
            
            % Hiding some components during initialization
            app.SimulateButton.Visible = 'off';
            app.StatusPanel.Visible = 'off';
            app.InputParametersPanel.Visible = 'off';
            app.Button.Visible = 'off';
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.GridLayout9.Visible = 'off';
            app.HealthyLabel.Visible = 'off';
            app.InfectedLabel.Visible = 'off';
            app.RecoveredLabel.Visible = 'off';

            % Display "Initializing" while the WepApp loads Simulink;
            app.initializingLabel.Visible = 'on';
            drawnow;
            pause(1)
            
            start_simulink; % Loading Simulink, takes some time
            app.initializingLabel.Visible = 'off';

            
            app.Label.Text = 'Click "Simulate" to get started.';
            app.sliderNames = ["Rate of Infection: ", ...
                "Social Interaction: ", ...
                "Recovery Period: "];
            
            % Initialize the slider panel titles with the current value
            app.RateofInfectionPanel.Title = app.sliderNames(1) + num2str(round(app.AgentSizeSlider.Value,2));
            app.SocialInteractionPanel.Title = app.sliderNames(2) + num2str(round(app.InitialVelocitySlider.Value,2));
            app.RecoveryPeriodPanel.Title = app.sliderNames(3) + num2str(round(app.ServiceTimeSlider.Value,2));
             
           % Remove axes toolbar from charts
            app.UIAxes2.Toolbar.Visible = "off";
            app.UIAxes.Toolbar.Visible = "off";

            % Display components hidden during initialization
            app.SimulateButton.Visible = 'on';
            app.StatusPanel.Visible = 'on';
            app.InputParametersPanel.Visible = 'on';
            app.Button.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.GridLayout9.Visible = 'on';
            app.HealthyLabel.Visible = 'on';
            app.InfectedLabel.Visible = 'on';
            app.RecoveredLabel.Visible = 'on';
            drawnow;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SimulatinganExponentialSpreadUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {543, 543};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Freeze controls
            app.Label.FontColor = app.SimulateButton.BackgroundColor;
            app.Label.FontWeight = 'bold';
            app.Label.Text = 'Simulating...';
            app.AgentSizeSlider.Enable = false;
            app.InitialVelocitySlider.Enable =  false;
            app.ServiceTimeSlider.Enable = false;
            app.SimulateButton.Enable = false;
            drawnow;
            
            % Run a modal progress dialog while simulating
            pd = uiprogressdlg(app.SimulatinganExponentialSpreadUIFigure,...
                "Indeterminate","on",...
                "Title","Simulating",...
                "Message","Running simulation...");
            
            % Get Parameter values from the app
            R = app.AgentSizeSlider.Value*0.025;
            Vmax = app.InitialVelocitySlider.Value*0.4;
            serverTime = app.ServiceTimeSlider.Value*30;
    
            out = runSim(R, Vmax, serverTime);
           
            % Close progress dialog
            pd.Message = "Finishing simulation...";
            pause(0.5);
            close(pd);
            
            % Wait icon
            app.Image.Visible = "on";
            
            % Animate the results
            app.Label.Text = 'Animating results...';
            drawnow;
            app.animateSim(app.AgentSizeSlider.Value*0.025,out);
            
            % Remove wait icon
            app.Image.Visible = "off";
            
            % Unfreeze the controls
            app.Label.FontColor = 'k';
            app.Label.FontWeight = 'normal';
            app.Label.Text = 'Completed';
            app.AgentSizeSlider.Enable = true;
            app.InitialVelocitySlider.Enable =  true;
            app.ServiceTimeSlider.Enable = true;
            app.SimulateButton.Enable = true;
            drawnow;
        end

        % Value changing function: AgentSizeSlider
        function AgentSizeSliderValueChanging(app, event)
            val = event.Value;
            app.RateofInfectionPanel.Title = app.sliderNames(1) + num2str(round(val,2));
        end

        % Value changing function: InitialVelocitySlider
        function InitialVelocitySliderValueChanging(app, event)
            val = event.Value;
            app.SocialInteractionPanel.Title = app.sliderNames(2) + num2str(round(val,2));
        end

        % Value changing function: ServiceTimeSlider
        function ServiceTimeSliderValueChanging(app, event)
            val = event.Value;
            app.RecoveryPeriodPanel.Title = app.sliderNames(3) + num2str(round(val,2));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             msg = sprintf(['This app simulates the evolution of an exponential spread, such as the propagation of a virus.'...
%                 'To learn more, visit Guy''s blog COVID-19: Simulating Exponential Spread in Simulink.\n\n'...
%                 'https://blogs.mathworks.com/simulink/2020/03/23/covid-19-simulating-exponential-spread-in-simulink']);
%             appTitle = 'More Info';
%             
%             uiconfirm(app.SimulatinganExponentialSpreadUIFigure,msg,appTitle,'Icon','info',...
%                 'Options',{'Close'});


            blogURL = "https://blogs.mathworks.com/simulink/2020/03/23/covid-19-simulating-exponential-spread-in-simulink";
            msg = ["This app simulates the evolution of an exponential spread, such as the propagation of a virus", ...
                ];
            appTitle = "More Info";
           
            selection = uiconfirm(app.SimulatinganExponentialSpreadUIFigure,msg,appTitle,"Icon","info",...
                "Options", ["Visit Blog","Close"]);
            if selection == "Visit Blog"
                web(blogURL);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulatinganExponentialSpreadUIFigure = uifigure('Visible', 'off');
            app.SimulatinganExponentialSpreadUIFigure.Name = 'Simulating an Exponential Spread';
            app.SimulatinganExponentialSpreadUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SimulatinganExponentialSpreadUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.SimulateButton = uibutton(app.GridLayout3, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StatusPanel = uipanel(app.GridLayout3);
            app.StatusPanel.Title = 'Status';
            app.GridLayout7 = uigridlayout(app.StatusPanel);
            app.Label = uilabel(app.GridLayout7);
            app.Label.Text = '';
            app.Image = uiimage(app.GridLayout7);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'waitIcon2.gif';
            app.InputParametersPanel = uipanel(app.GridLayout3);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.GridLayout8 = uigridlayout(app.InputParametersPanel);
            app.RateofInfectionPanel = uipanel(app.GridLayout8);
            app.RateofInfectionPanel.Title = 'Rate of Infection:';
            app.GridLayout4 = uigridlayout(app.RateofInfectionPanel);
            app.AgentSizeSlider = uislider(app.GridLayout4);
            app.AgentSizeSlider.Limits = [0.1 1];
            app.AgentSizeSlider.MajorTicks = [0 0.5 1];
            app.AgentSizeSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.AgentSizeSlider.ValueChangingFcn = createCallbackFcn(app, @AgentSizeSliderValueChanging, true);
            app.AgentSizeSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.AgentSizeSlider.Tooltip = {'Agent size: Infectiousness of each agent'};
            app.AgentSizeSlider.Value = 0.8;
            app.SocialInteractionPanel = uipanel(app.GridLayout8);
            app.SocialInteractionPanel.Title = 'Social Interaction: ';
            app.GridLayout5 = uigridlayout(app.SocialInteractionPanel);
            app.InitialVelocitySlider = uislider(app.GridLayout5);
            app.InitialVelocitySlider.Limits = [0.1 1];
            app.InitialVelocitySlider.MajorTicks = [0 0.5 1];
            app.InitialVelocitySlider.ValueChangingFcn = createCallbackFcn(app, @InitialVelocitySliderValueChanging, true);
            app.InitialVelocitySlider.Tooltip = {'Initial velocity: Frequency of social distancing'};
            app.InitialVelocitySlider.Value = 0.75;
            app.RecoveryPeriodPanel = uipanel(app.GridLayout8);
            app.RecoveryPeriodPanel.Title = 'Recovery Period:';
            app.GridLayout6 = uigridlayout(app.RecoveryPeriodPanel);
            app.ServiceTimeSlider = uislider(app.GridLayout6);
            app.ServiceTimeSlider.Limits = [0.1 1];
            app.ServiceTimeSlider.MajorTicks = [0 0.5 1];
            app.ServiceTimeSlider.ValueChangingFcn = createCallbackFcn(app, @ServiceTimeSliderValueChanging, true);
            app.ServiceTimeSlider.Tooltip = {'Service time: Duration of infection before becoming immune  '};
            app.ServiceTimeSlider.Value = 0.75;
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, '% of Population')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 10 20 30 40 50];
            app.UIAxes.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Interaction Model')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.HealthyLabel = uilabel(app.GridLayout9);
            app.HealthyLabel.Text = 'Healthy';
            app.InfectedLabel = uilabel(app.GridLayout9);
            app.InfectedLabel.Text = 'Infected';
            app.RecoveredLabel = uilabel(app.GridLayout9);
            app.RecoveredLabel.Text = 'Recovered';
            app.Panel = uipanel(app.GridLayout9);
            app.Panel_2 = uipanel(app.GridLayout9);
            app.Panel_3 = uipanel(app.GridLayout9);
            app.initializingLabel = uilabel(app.GridLayout2);
            app.initializingLabel.Text = 'Initializing...';
            % Show the figure after all components are created
            app.SimulatinganExponentialSpreadUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = virusSpread

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulatinganExponentialSpreadUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulatinganExponentialSpreadUIFigure)
        end
    end
end